classdef CatanAppInputBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure            % UI Figure
        calculate   matlab.ui.control.Button    % Calculate
        TV20        matlab.ui.control.Label    
        TV8         matlab.ui.control.Label    
        TV10        matlab.ui.control.Label    
        TV22        matlab.ui.control.Label    
        TV12        matlab.ui.control.Label    
        TV24        matlab.ui.control.Label    
        TV14        matlab.ui.control.Label    
        TV26        matlab.ui.control.Label    
        TV16        matlab.ui.control.Label    
        TV9         matlab.ui.control.Label    
        V1          matlab.ui.control.Label    
        V11         matlab.ui.control.Label    
        V3          matlab.ui.control.Label    
        V13         matlab.ui.control.Label    
        V4          matlab.ui.control.Label    
        V5          matlab.ui.control.Label    
        V15         matlab.ui.control.Label    
        V6          matlab.ui.control.Label    
        V7          matlab.ui.control.Label    
        V28         matlab.ui.control.Label    
        V30         matlab.ui.control.Label    
        V17         matlab.ui.control.Label    
        V19         matlab.ui.control.Label    
        V32         matlab.ui.control.Label    
        V21         matlab.ui.control.Label    
        V34         matlab.ui.control.Label    
        V23         matlab.ui.control.Label    
        V36         matlab.ui.control.Label    
        V25         matlab.ui.control.Label    
        V18         matlab.ui.control.Label    
        V38         matlab.ui.control.Label    
        V27         matlab.ui.control.Label    
        V41         matlab.ui.control.Label    
        V29         matlab.ui.control.Label    
        V31         matlab.ui.control.Label    
        V43         matlab.ui.control.Label    
        V33         matlab.ui.control.Label    
        V45         matlab.ui.control.Label    
        V35         matlab.ui.control.Label    
        V47         matlab.ui.control.Label    
        V37         matlab.ui.control.Label    
        V40         matlab.ui.control.Label    
        V42         matlab.ui.control.Label    
        V44         matlab.ui.control.Label    
        V46         matlab.ui.control.Label    
        V39         matlab.ui.control.Label    
        V49         matlab.ui.control.Label    
        V51         matlab.ui.control.Label    
        V53         matlab.ui.control.Label    
        V48         matlab.ui.control.Label    
        V50         matlab.ui.control.Label    
        V52         matlab.ui.control.Label    
        V54         matlab.ui.control.Label    
        Label379    matlab.ui.control.Label     % R4
        boardTile4  matlab.ui.control.EditField
        Label380    matlab.ui.control.Label     % D4
        boardRoll4  matlab.ui.control.EditField
        Label381    matlab.ui.control.Label     % R5
        boardTile5  matlab.ui.control.EditField
        Label382    matlab.ui.control.Label     % D5
        boardRoll5  matlab.ui.control.EditField
        Label383    matlab.ui.control.Label     % R6
        boardTile6  matlab.ui.control.EditField
        Label384    matlab.ui.control.Label     % D6
        boardRoll6  matlab.ui.control.EditField
        Label385    matlab.ui.control.Label     % R7
        boardTile7  matlab.ui.control.EditField
        Label386    matlab.ui.control.Label     % D7
        boardRoll7  matlab.ui.control.EditField
        Label387    matlab.ui.control.Label     % R1
        boardTile1  matlab.ui.control.EditField % resource (number)
        Label388    matlab.ui.control.Label     % D1
        boardRoll1  matlab.ui.control.EditField % dice value
        Label389    matlab.ui.control.Label     % R2
        boardTile2  matlab.ui.control.EditField
        Label390    matlab.ui.control.Label     % D2
        boardRoll2  matlab.ui.control.EditField
        Label391    matlab.ui.control.Label     % R3
        boardTile3  matlab.ui.control.EditField
        Label392    matlab.ui.control.Label     % D3
        boardRoll3  matlab.ui.control.EditField
        Label393    matlab.ui.control.Label     % R8
        boardTile8  matlab.ui.control.EditField
        Label394    matlab.ui.control.Label     % D8
        boardRoll8  matlab.ui.control.EditField
        Label395    matlab.ui.control.Label     % R9
        boardTile9  matlab.ui.control.EditField
        Label396    matlab.ui.control.Label     % D9
        boardRoll9  matlab.ui.control.EditField
        Label397    matlab.ui.control.Label     % R10
        boardTile10 matlab.ui.control.EditField
        Label398    matlab.ui.control.Label     % D10
        boardRoll10 matlab.ui.control.EditField
        Label399    matlab.ui.control.Label     % R11
        boardTile11 matlab.ui.control.EditField
        Label400    matlab.ui.control.Label     % D11
        boardRoll11 matlab.ui.control.EditField
        boardTile12 matlab.ui.control.EditField
        boardRoll12 matlab.ui.control.EditField
        Label403    matlab.ui.control.Label     % R13
        boardTile13 matlab.ui.control.EditField
        Label404    matlab.ui.control.Label     % D13
        boardRoll13 matlab.ui.control.EditField
        Label405    matlab.ui.control.Label     % R14
        boardTile14 matlab.ui.control.EditField
        Label406    matlab.ui.control.Label     % D14
        boardRoll14 matlab.ui.control.EditField
        Label407    matlab.ui.control.Label     % R15
        boardTile15 matlab.ui.control.EditField
        Label408    matlab.ui.control.Label     % D15
        boardRoll15 matlab.ui.control.EditField
        Label409    matlab.ui.control.Label     % R16
        Label410    matlab.ui.control.Label     % D16
        Label411    matlab.ui.control.Label     % R17
        boardTile17 matlab.ui.control.EditField
        Label412    matlab.ui.control.Label     % D17
        boardRoll17 matlab.ui.control.EditField
        Label413    matlab.ui.control.Label     % R18
        boardTile18 matlab.ui.control.EditField
        Label414    matlab.ui.control.Label     % D18
        boardRoll18 matlab.ui.control.EditField
        Label415    matlab.ui.control.Label     % R19
        boardTile19 matlab.ui.control.EditField
        Label416    matlab.ui.control.Label     % D19
        boardRoll19 matlab.ui.control.EditField
        TV2         matlab.ui.control.Label    
        Label401    matlab.ui.control.Label     % R12
        Label402    matlab.ui.control.Label     % D12
        boardTile16 matlab.ui.control.EditField
        boardRoll16 matlab.ui.control.EditField
        Label417    matlab.ui.control.Label     % 1 = wood
        Label418    matlab.ui.control.Label     % 2 = brick
        Label419    matlab.ui.control.Label     % 3 = wheat
        Label420    matlab.ui.control.Label     % 4 = sheep
        Label421    matlab.ui.control.Label     % 5 = stone
        Label422    matlab.ui.control.Label     % 0 = desert
        Label423    matlab.ui.control.Label     % ** for desert, dice value...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % calculate button pushed function
        function calculate_starting_position(app)
%Jared Hall, Chase Grimm
%edited by josiah green 11/29

clear,clc
%Definition of Variables;

p = 1/36*[0,1,2,3,4,5,0,5,4,3,2,1];

AvgRoad = 11; %Upper bound for max settlements...can we find a better value?
AvgDev = 3; %Largest army = ev(6 Dev Cards), but winner has average 3 purchased
AvgKnight = .56*AvgDev;

%Expected Values

DevVal = .89575;
S = .434534; %Solved SLE 2/(Avg#Roads2Win)=B+W, 1=S+B+W+WH, 1=2WH+3O, DevVal = S+V+O, 1.9367B=W (Weighted values of B and W based on "Settlers of Catan Analysis" pg 7 (Keep 2011) developingcatan.files.wordpress.com/2011/02/settlers-of-catan-analysis.pdf)
O = .0775682;
WH = .383648;
B = .061912;
W = .11991;
ResourceValues = [W,B,WH,S,O];

%Note: consider setting B=W and then taking Keep's weighting into account later on in program.
% Generate catan board

%%%%%%%%%%%%%%%%%
% 1 = wood
% 2 = brick
% 3 = wheat
% 4 = sheep
% 5 = stone
%%%%%%%%%%%%%%%%%

% vector of tiles
tiles = [1,1,1,1,2,2,2,3,3,3,3,4,4,4,4,5,5,5];

% number tiles
rolls = [11,12,9,4,6,5,10,3,11,4,8,8,10,9,3,5,2,6];
rolls = sort(rolls);

%generate board tiles and rolls

%jg edit: random generation
%boardTiles = randsample(tiles,18);
%boardRolls = randsample(rolls,18);

%jg edit: input values

%convert textbox value to number
r1=str2num(get(app.boardTile1, 'Value'));
r2=str2num(get(app.boardTile2, 'Value'));
r3=str2num(get(app.boardTile3, 'Value'));
r4=str2num(get(app.boardTile4, 'Value'));
r5=str2num(get(app.boardTile5, 'Value'));
r6=str2num(get(app.boardTile6, 'Value'));
r7=str2num(get(app.boardTile7, 'Value'));
r8=str2num(get(app.boardTile8, 'Value'));
r9=str2num(get(app.boardTile9, 'Value'));
r10=str2num(get(app.boardTile10, 'Value'));
r11=str2num(get(app.boardTile11, 'Value'));
r12=str2num(get(app.boardTile12, 'Value'));
r13=str2num(get(app.boardTile13, 'Value'));
r14=str2num(get(app.boardTile14, 'Value'));
r15=str2num(get(app.boardTile15, 'Value'));
r16=str2num(get(app.boardTile16, 'Value'));
r17=str2num(get(app.boardTile17, 'Value'));
r18=str2num(get(app.boardTile18, 'Value'));
r19=str2num(get(app.boardTile19, 'Value'));

d1=str2num(get(app.boardRoll1, 'Value'));
d2=str2num(get(app.boardRoll2, 'Value'));
d3=str2num(get(app.boardRoll3, 'Value'));
d4=str2num(get(app.boardRoll4, 'Value'));
d5=str2num(get(app.boardRoll5, 'Value'));
d6=str2num(get(app.boardRoll6, 'Value'));
d7=str2num(get(app.boardRoll7, 'Value'));
d8=str2num(get(app.boardRoll8, 'Value'));
d9=str2num(get(app.boardRoll9, 'Value'));
d10=str2num(get(app.boardRoll10, 'Value'));
d11=str2num(get(app.boardRoll11, 'Value'));
d12=str2num(get(app.boardRoll12, 'Value'));
d13=str2num(get(app.boardRoll13, 'Value'));
d14=str2num(get(app.boardRoll14, 'Value'));
d15=str2num(get(app.boardRoll15, 'Value'));
d16=str2num(get(app.boardRoll16, 'Value'));
d17=str2num(get(app.boardRoll17, 'Value'));
d18=str2num(get(app.boardRoll18, 'Value'));
d19=str2num(get(app.boardRoll19, 'Value'));

%poplulate vectors
boardTiles = [r1, r2, r3, r4, r5, r6, r7, r8, r9, r10, r11, r12, r13, r14, r15, r16, r17, r18, r19];
boardRolls = [d1, d2, d3, d4, d5, d6, d7, d8, d9, d10, d11, d12, d13, d14, d15, d16, d17, d18, d19];

% insert the desert tile
%jg edit: not necessary with manual input
%desert = randi([2 18],1,1);
%boardTiles = [boardTiles(1:desert-1),0,boardTiles(desert:18)];
%boardRolls = [boardRolls(1:desert-1),0,boardRolls(desert:18)];

% Assign values to tiles
TV = zeros(1,12);
for i = 1:19;
    for j = 1:5;
        if boardTiles(i) == j;
            TV(i) = ResourceValues(j);
        else end
    end
    for k = 1:12;
        if boardRolls(i) == k;
            TV(i) = p(k)*TV(i);
        else end
    end
end
PlaceValues = zeros(1,54);
%                 1     2          3        4           5        6      7     8         9             10                11                    12                   13                14               15       16    17       18             19                  20                  21                22                   23                      24                 25                 26        27     28     29               30                  31                 32                      33                     34                  35                        36                 37          38     39         40                41                  42                    43                       44                   45                   46         47     48     49          50           51        52          53       54
PlaceValues = [TV(1), TV(1), TV(1)+TV(2), TV(2), TV(2)+TV(3), TV(3), TV(3), TV(4), TV(1)+TV(4), TV(1)+TV(4)+TV(5), TV(1)+TV(2)+TV(5), TV(2)+TV(5)+TV(6), TV(2)+TV(3)+TV(6), TV(3)+TV(6)+TV(7), TV(3)+TV(7), TV(7), TV(8), TV(4)+TV(8), TV(4)+TV(8)+TV(9), TV(4)+TV(5)+TV(9), TV(5)+TV(9)+TV(10), TV(5)+TV(6)+TV(10), TV(6)+TV(10)+TV(11), TV(6)+TV(7)+TV(11), TV(7)+TV(11)+TV(12), TV(7)+TV(12), TV(12), TV(8), TV(8)+TV(13), TV(8)+TV(9)+TV(13), TV(9)+TV(13)+TV(14), TV(9)+TV(10)+TV(14), TV(10)+TV(14)+TV(15), TV(10)+TV(11)+TV(15), TV(11)+TV(15)+TV(16), TV(11)+TV(12)+TV(16), TV(12)+TV(16), TV(12), TV(13), TV(13)+TV(17), TV(13)+TV(14)+TV(17), TV(14)+TV(17)+TV(18), TV(14)+TV(15)+TV(18), TV(15)+TV(18)+TV(19), TV(15)+TV(16)+TV(19), TV(16)+TV(19), TV(16), TV(17), TV(17), TV(17)+TV(18), TV(18), TV(18)+TV(19), TV(19), TV(19)];
[~, PlaceRanks] = sort(PlaceValues, 'descend');

% find top placements
[~, TopPlacement] = sort(PlaceRanks, 'ascend');

top1 = num2str(TopPlacement(1));
top2 = num2str(TopPlacement(2));
top3 = num2str(TopPlacement(3));
top4 = num2str(TopPlacement(4));
top5 = num2str(TopPlacement(5));
top6 = num2str(TopPlacement(6));
top7 = num2str(TopPlacement(7));
top8 = num2str(TopPlacement(8));
top9 = num2str(TopPlacement(9));
top10 = numstr(TopPlacement(10));
top11 = num2str(TopPlacement(11));
top12 = num2str(TopPlacement(12));

%populate blank lables with respective values

label1 = strcat('app.V',top1);
label2 = strcat('app.V',top2);
label3 = strcat('app.V',top3);
label4 = strcat('app.V',top4);
label5 = strcat('app.V',top5);
label6 = strcat('app.V',top6);
label7 = strcat('app.V',top7);
label8 = strcat('app.V',top8);
label9 = strcat('app.V',top9);
label10 = strcat('app.V',top10);
label11 = strcat('app.V',top11);
label12 = strcat('app.V',top12);

set(label1, 'Text', 1);
set(label2, 'Text', 2);
set(label3, 'Text', 3);
set(label4, 'Text', 4);
set(label5, 'Text', 5);
set(label6, 'Text', 6);
set(label7, 'Text', 7);
set(label8, 'Text', 8);
set(label9, 'Text', 9);
set(label10, 'Text', 10);
set(label11, 'Text', 11);
set(label12, 'Text', 12);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculate_starting_position);
            app.calculate.Text = 'Calculate';
            app.TV20 = uilabel(app.UIFigure);
            app.TV20.Text = '';
            app.TV8 = uilabel(app.UIFigure);
            app.TV8.Text = '';
            app.TV10 = uilabel(app.UIFigure);
            app.TV10.Text = '';
            app.TV22 = uilabel(app.UIFigure);
            app.TV22.Text = '';
            app.TV12 = uilabel(app.UIFigure);
            app.TV12.Text = '';
            app.TV24 = uilabel(app.UIFigure);
            app.TV24.Text = '';
            app.TV14 = uilabel(app.UIFigure);
            app.TV14.Text = '';
            app.TV26 = uilabel(app.UIFigure);
            app.TV26.Text = '';
            app.TV16 = uilabel(app.UIFigure);
            app.TV16.Text = '';
            app.TV9 = uilabel(app.UIFigure);
            app.TV9.Text = '';
            app.V1 = uilabel(app.UIFigure);
            app.V1.Text = '';
            app.V11 = uilabel(app.UIFigure);
            app.V11.Text = '';
            app.V3 = uilabel(app.UIFigure);
            app.V3.Text = '';
            app.V13 = uilabel(app.UIFigure);
            app.V13.Text = '';
            app.V4 = uilabel(app.UIFigure);
            app.V4.Text = '';
            app.V5 = uilabel(app.UIFigure);
            app.V5.Text = '';
            app.V15 = uilabel(app.UIFigure);
            app.V15.Text = '';
            app.V6 = uilabel(app.UIFigure);
            app.V6.Text = '';
            app.V7 = uilabel(app.UIFigure);
            app.V7.Text = '';
            app.V28 = uilabel(app.UIFigure);
            app.V28.Text = '';
            app.V30 = uilabel(app.UIFigure);
            app.V30.Text = '';
            app.V17 = uilabel(app.UIFigure);
            app.V17.Text = '';
            app.V19 = uilabel(app.UIFigure);
            app.V19.Text = '';
            app.V32 = uilabel(app.UIFigure);
            app.V32.Text = '';
            app.V21 = uilabel(app.UIFigure);
            app.V21.Text = '';
            app.V34 = uilabel(app.UIFigure);
            app.V34.Text = '';
            app.V23 = uilabel(app.UIFigure);
            app.V23.Text = '';
            app.V36 = uilabel(app.UIFigure);
            app.V36.Text = '';
            app.V25 = uilabel(app.UIFigure);
            app.V25.Text = '';
            app.V18 = uilabel(app.UIFigure);
            app.V18.Text = '';
            app.V38 = uilabel(app.UIFigure);
            app.V38.Text = '';
            app.V27 = uilabel(app.UIFigure);
            app.V27.Text = '';
            app.V41 = uilabel(app.UIFigure);
            app.V41.Text = '';
            app.V29 = uilabel(app.UIFigure);
            app.V29.Text = '';
            app.V31 = uilabel(app.UIFigure);
            app.V31.Text = '';
            app.V43 = uilabel(app.UIFigure);
            app.V43.Text = '';
            app.V33 = uilabel(app.UIFigure);
            app.V33.Text = '';
            app.V45 = uilabel(app.UIFigure);
            app.V45.Text = '';
            app.V35 = uilabel(app.UIFigure);
            app.V35.Text = '';
            app.V47 = uilabel(app.UIFigure);
            app.V47.Text = '';
            app.V37 = uilabel(app.UIFigure);
            app.V37.Text = '';
            app.V40 = uilabel(app.UIFigure);
            app.V40.Text = '';
            app.V42 = uilabel(app.UIFigure);
            app.V42.Text = '';
            app.V44 = uilabel(app.UIFigure);
            app.V44.Text = '';
            app.V46 = uilabel(app.UIFigure);
            app.V46.Text = '';
            app.V39 = uilabel(app.UIFigure);
            app.V39.Text = '';
            app.V49 = uilabel(app.UIFigure);
            app.V49.Text = '';
            app.V51 = uilabel(app.UIFigure);
            app.V51.Text = '';
            app.V53 = uilabel(app.UIFigure);
            app.V53.Text = '';
            app.V48 = uilabel(app.UIFigure);
            app.V48.Text = '';
            app.V50 = uilabel(app.UIFigure);
            app.V50.Text = '';
            app.V52 = uilabel(app.UIFigure);
            app.V52.Text = '';
            app.V54 = uilabel(app.UIFigure);
            app.V54.Text = '';
            app.Label379 = uilabel(app.UIFigure);
            app.Label379.Text = 'R4';
            app.boardTile4 = uieditfield(app.UIFigure, 'text');
            app.Label380 = uilabel(app.UIFigure);
            app.Label380.Text = 'D4';
            app.boardRoll4 = uieditfield(app.UIFigure, 'text');
            app.Label381 = uilabel(app.UIFigure);
            app.Label381.Text = 'R5';
            app.boardTile5 = uieditfield(app.UIFigure, 'text');
            app.Label382 = uilabel(app.UIFigure);
            app.Label382.Text = 'D5';
            app.boardRoll5 = uieditfield(app.UIFigure, 'text');
            app.Label383 = uilabel(app.UIFigure);
            app.Label383.Text = 'R6';
            app.boardTile6 = uieditfield(app.UIFigure, 'text');
            app.Label384 = uilabel(app.UIFigure);
            app.Label384.Text = 'D6';
            app.boardRoll6 = uieditfield(app.UIFigure, 'text');
            app.Label385 = uilabel(app.UIFigure);
            app.Label385.Text = 'R7';
            app.boardTile7 = uieditfield(app.UIFigure, 'text');
            app.Label386 = uilabel(app.UIFigure);
            app.Label386.Text = 'D7';
            app.boardRoll7 = uieditfield(app.UIFigure, 'text');
            app.Label387 = uilabel(app.UIFigure);
            app.Label387.Text = 'R1';
            app.boardTile1 = uieditfield(app.UIFigure, 'text');
            app.boardTile1.Value = 'resource (number)';
            app.Label388 = uilabel(app.UIFigure);
            app.Label388.Text = 'D1';
            app.boardRoll1 = uieditfield(app.UIFigure, 'text');
            app.boardRoll1.Value = 'dice value';
            app.Label389 = uilabel(app.UIFigure);
            app.Label389.Text = 'R2';
            app.boardTile2 = uieditfield(app.UIFigure, 'text');
            app.Label390 = uilabel(app.UIFigure);
            app.Label390.Text = 'D2';
            app.boardRoll2 = uieditfield(app.UIFigure, 'text');
            app.Label391 = uilabel(app.UIFigure);
            app.Label391.Text = 'R3';
            app.boardTile3 = uieditfield(app.UIFigure, 'text');
            app.Label392 = uilabel(app.UIFigure);
            app.Label392.Text = 'D3';
            app.boardRoll3 = uieditfield(app.UIFigure, 'text');
            app.Label393 = uilabel(app.UIFigure);
            app.Label393.Text = 'R8';
            app.boardTile8 = uieditfield(app.UIFigure, 'text');
            app.Label394 = uilabel(app.UIFigure);
            app.Label394.Text = 'D8';
            app.boardRoll8 = uieditfield(app.UIFigure, 'text');
            app.Label395 = uilabel(app.UIFigure);
            app.Label395.Text = 'R9';
            app.boardTile9 = uieditfield(app.UIFigure, 'text');
            app.Label396 = uilabel(app.UIFigure);
            app.Label396.Text = 'D9';
            app.boardRoll9 = uieditfield(app.UIFigure, 'text');
            app.Label397 = uilabel(app.UIFigure);
            app.Label397.Text = 'R10';
            app.boardTile10 = uieditfield(app.UIFigure, 'text');
            app.Label398 = uilabel(app.UIFigure);
            app.Label398.Text = 'D10';
            app.boardRoll10 = uieditfield(app.UIFigure, 'text');
            app.Label399 = uilabel(app.UIFigure);
            app.Label399.Text = 'R11';
            app.boardTile11 = uieditfield(app.UIFigure, 'text');
            app.Label400 = uilabel(app.UIFigure);
            app.Label400.Text = 'D11';
            app.boardRoll11 = uieditfield(app.UIFigure, 'text');
            app.boardTile12 = uieditfield(app.UIFigure, 'text');
            app.boardRoll12 = uieditfield(app.UIFigure, 'text');
            app.Label403 = uilabel(app.UIFigure);
            app.Label403.Text = 'R13';
            app.boardTile13 = uieditfield(app.UIFigure, 'text');
            app.Label404 = uilabel(app.UIFigure);
            app.Label404.Text = 'D13';
            app.boardRoll13 = uieditfield(app.UIFigure, 'text');
            app.Label405 = uilabel(app.UIFigure);
            app.Label405.Text = 'R14';
            app.boardTile14 = uieditfield(app.UIFigure, 'text');
            app.Label406 = uilabel(app.UIFigure);
            app.Label406.Text = 'D14';
            app.boardRoll14 = uieditfield(app.UIFigure, 'text');
            app.Label407 = uilabel(app.UIFigure);
            app.Label407.Text = 'R15';
            app.boardTile15 = uieditfield(app.UIFigure, 'text');
            app.Label408 = uilabel(app.UIFigure);
            app.Label408.Text = 'D15';
            app.boardRoll15 = uieditfield(app.UIFigure, 'text');
            app.Label409 = uilabel(app.UIFigure);
            app.Label409.Text = 'R16';
            app.Label410 = uilabel(app.UIFigure);
            app.Label410.Text = 'D16';
            app.Label411 = uilabel(app.UIFigure);
            app.Label411.Text = 'R17';
            app.boardTile17 = uieditfield(app.UIFigure, 'text');
            app.Label412 = uilabel(app.UIFigure);
            app.Label412.Text = 'D17';
            app.boardRoll17 = uieditfield(app.UIFigure, 'text');
            app.Label413 = uilabel(app.UIFigure);
            app.Label413.Text = 'R18';
            app.boardTile18 = uieditfield(app.UIFigure, 'text');
            app.Label414 = uilabel(app.UIFigure);
            app.Label414.Text = 'D18';
            app.boardRoll18 = uieditfield(app.UIFigure, 'text');
            app.Label415 = uilabel(app.UIFigure);
            app.Label415.Text = 'R19';
            app.boardTile19 = uieditfield(app.UIFigure, 'text');
            app.Label416 = uilabel(app.UIFigure);
            app.Label416.Text = 'D19';
            app.boardRoll19 = uieditfield(app.UIFigure, 'text');
            app.TV2 = uilabel(app.UIFigure);
            app.TV2.Text = '';
            app.Label401 = uilabel(app.UIFigure);
            app.Label401.Text = 'R12';
            app.Label402 = uilabel(app.UIFigure);
            app.Label402.Text = 'D12';
            app.boardTile16 = uieditfield(app.UIFigure, 'text');
            app.boardRoll16 = uieditfield(app.UIFigure, 'text');
            app.Label417 = uilabel(app.UIFigure);
            app.Label417.Text = '1 = wood';
            app.Label418 = uilabel(app.UIFigure);
            app.Label418.Text = '2 = brick';
            app.Label419 = uilabel(app.UIFigure);
            app.Label419.Text = '3 = wheat';
            app.Label420 = uilabel(app.UIFigure);
            app.Label420.Text = '4 = sheep';
            app.Label421 = uilabel(app.UIFigure);
            app.Label421.Text = '5 = stone';
            app.Label422 = uilabel(app.UIFigure);
            app.Label422.Text = '0 = desert';
            app.Label423 = uilabel(app.UIFigure);
            app.Label423.Text = '** for desert, dice value = 0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CatanAppInputBoard()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end